classdef MSE491Project1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        troubleshootTextArea          matlab.ui.control.TextArea
        troubleshootTextAreaLabel     matlab.ui.control.Label
        BPMEditField                  matlab.ui.control.NumericEditField
        BPMEditFieldLabel             matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        AddConvolutionReverbButton    matlab.ui.control.Button
        AddEchoEffectButton           matlab.ui.control.Button
        AddRythmaticAutoPanningEffectButton  matlab.ui.control.Button
        TremeloEffectErrorText        matlab.ui.control.TextArea
        EndTremoloEffect              matlab.ui.control.NumericEditField
        EndTimesEditField_2Label_2    matlab.ui.control.Label
        StartTremoloEffect            matlab.ui.control.NumericEditField
        StartTimesEditField_2Label_2  matlab.ui.control.Label
        TremoloEffectOptions          matlab.ui.control.ListBox
        AmplitudeDepthSlider          matlab.ui.control.Slider
        AmplitudeDepthLabel           matlab.ui.control.Label
        FadeEffectErrorText           matlab.ui.control.TextArea
        LFOFrequencyHzSpinner         matlab.ui.control.Spinner
        LFOFrequencyHzSpinnerLabel    matlab.ui.control.Label
        AddTremoloEffectButton        matlab.ui.control.Button
        UndoLastEffectButton          matlab.ui.control.Button
        PlayAudioButton               matlab.ui.control.Button
        AddFadeEffectButton           matlab.ui.control.Button
        TypeOfFade                    matlab.ui.container.ButtonGroup
        FadeOutButton                 matlab.ui.control.RadioButton
        FadeInButton                  matlab.ui.control.RadioButton
        FadeDegreeSlider              matlab.ui.control.Slider
        FadeDegreeSliderLabel         matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        EndFadeInTime                 matlab.ui.control.NumericEditField
        EndTimesEditField_2Label      matlab.ui.control.Label
        StartFadeInTime               matlab.ui.control.NumericEditField
        StartTimesEditField_2Label    matlab.ui.control.Label
        SelectAudioFIleText           matlab.ui.control.TextArea
        SelectAudioFIleButton         matlab.ui.control.Button
        InputSignal                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        %From Function SelectAudioFileButtonPushed()
        file; %the file name
        filestatus; %if a file was added or not. == 1 when usedr added a file, == 0 if user didn't add file
        signal; % The initial Signal
        Fs; % The sampling Rate of the initial signal
        fftsignal; % performing the fourier transform onto the initial signal
        beforeEffect;
    end
    
    methods (Access = private)
        
        function results = plotSignal(app) %#ok<STOUT> 
            t = 0:1/app.Fs:length(app.signal)/app.Fs;
            t = t(1:length(t)-1);
            plot(app.InputSignal,t ,app.signal);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: SelectAudioFIleButton, UIFigure
        function SelectAudioFIleButtonPushed(app, event)
            [app.file, path] = uigetfile({'*.wav'}, 'Select An Audio File');
            if path ~= 0 %if a file was selected
                s1 = ' Selected';
                app.SelectAudioFIleText.Visible= 1;
                app.SelectAudioFIleText.Value = strcat(app.file, s1); %Let the used know the file was selected
                app.SelectAudioFIleText.FontColor = '0, 0.50, 0';
                app.SelectAudioFIleText.HorizontalAlignment = 'Center';
                app.filestatus = 1; %set the global variable filestatus to true, to allow other functions to know
                [app.signal, app.Fs] = audioread(app.file); %Read the file and store the initial signal in global variable along with its sampling frequency
                
                plotSignal(app);
            else
                app.filestatus = 0;
                app.SelectAudioFIleText.Value = ('No File Selected!');
                app.SelectAudioFIleText.FontColor = 'red';
                app.SelectAudioFIleText.HorizontalAlignment = 'Center';
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: AddFadeEffectButton
        function AddFadeEffectButtonPushed(app, event)
           start = app.StartFadeInTime.Value;
           finish = app.EndFadeInTime.Value;
           app.beforeEffect = app.signal;

           if (start >= 0 && start < finish && finish < ((length(app.signal)/app.Fs)))
               x = app.FadeDegreeSlider.Value;    
               app.FadeEffectErrorText.Visible = 0;
               %Create the input signal at the given times specified by the
               %user
               if (start == 0) %2 cases for when the time is = 0 and when time ~= 0 bc matlab starts at 1 annoyingly
               in = app.signal(1:finish*app.Fs,:);
                    if (app.FadeOutButton.Value == 1) % we do fade out
                        a = linspace(1, 0, length(in)); a = a(:);
                        fadeOut = a.^x; 
                        in = in.*fadeOut;
                    else %We do fade in
                        a = linspace(1, 0 , length(in)); a = a(:);
                        fadeIn = 1 - a.^x;
                        in = in.*fadeIn;
                    end
               
               app.signal(1:finish*app.Fs, :) = in;
               plotSignal(app)
               else                           
               in = app.signal(start*app.Fs:finish*app.Fs,:);

                    if (app.FadeOutButton.Value == 1) % we do fade out
                        a = linspace(1, 0, length(in)); a = a(:);
                        fadeOut =   a.^x; 
                        in = in.*fadeOut;
                    else %We do fade in
                        a = linspace(1, 0 , length(in)); a = a(:);
                        fadeIn = 1 - a.^x;
                        in = in.*fadeIn;
                    end
                    app.signal(start*app.Fs:finish*app.Fs, :) = in;
                    plotSignal(app)
               end
           else
               %inform user why it didn't work 
               app.FadeEffectErrorText.Visible = 1;
               app.FadeEffectErrorText.Value = "ERROR: Please Enter a Valid Time";
               app.FadeEffectErrorText.HorizontalAlignment = "center";
               app.FadeEffectErrorText.FontColor = "red";
           end 

        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            sound(app.signal, app.Fs);
        end

        % Button pushed function: UndoLastEffectButton
        function UndoLastEffectButtonPushed(app, event)
            app.signal = app.beforeEffect;
            plotSignal(app);
        end

        % Button pushed function: AddTremoloEffectButton
        function AddTremoloEffectButtonPushed(app, event)
            app.beforeEffect = app.signal; %save the previous signal for undo feature
            %Time the effect should be applied
            start = app.StartTremoloEffect.Value;
            finish = app.EndTremoloEffect.Value;
            %parameters
            depth = app.AmplitudeDepthSlider.Value;
            speed = app.LFOFrequencyHzSpinner.Value;
            amp = 0.5*(depth/100);
            offset = 1 - amp;
            
            if (start < finish && finish < ((length(app.signal)/app.Fs)))
                    app.TremeloEffectErrorText.Visible = 0;
                    t = (finish - start); %Time in seconds
                    t = t(:);   
                    %If statements to know what type of modulator to create
                    if (strcmp(app.TremoloEffectOptions.Value, "Sine Wave"))
                        mod = amp.*sin(2*pi*speed*t) + offset;
                    elseif (strcmp(app.TremoloEffectOptions.Value, "SawTooth"))
                        mod = amp.*sawtooth(2*pi*speed*t) + offset;
                    elseif (strcmp(app.TremoloEffectOptions.Value, "Square"))
                        mod = amp.*square(2*pi*speed*t) + offset;
                    else %Generates a triangle wave
                        mod = amp.*sawtooth(2*pi*speed*t, 0.5) + offset;
                    end

                    if (start == 0)
                        app.signal(1:finish*app.Fs) = app.signal(1:finish*app.Fs).*mod;
                    else
                        app.signal(start*app.Fs:finish*app.Fs) = app.signal(start*app.Fs:finish*app.Fs).*mod;
                    end
                    plotSignal(app);
            else
               app.TremeloEffectErrorText.Visible = 1;
               app.TremeloEffectErrorText.Value = "ERROR: Please Enter a Valid Time";
               app.TremeloEffectErrorText.HorizontalAlignment = "center";
               app.TremeloEffectErrorText.FontColor = "red";
            end
            

        end

        % Button pushed function: AddRythmaticAutoPanningEffectButton
        function AddRythmaticAutoPanningEffectButtonPushed(app, event)
            app.beforeEffect = app.signal; %save the previous signal for undo feature
            N = length(app.signal);
            t = [0:N-1]*(1/app.Fs);
            t = t(:);
            
            panValue = 100*sin(2*pi*1*t);
            x = panValue / 200 + 0.5;
            rightAmp = sin(x*pi/2);
            leftAmp = sin((1-x)*pi/2);

            app.signal = [leftAmp.*app.signal, rightAmp .*app.signal];
            plotSignal(app);
        end

        % Button pushed function: AddEchoEffectButton
        function AddEchoEffectButtonPushed(app, event)
            app.beforeEffect = app.signal;
            bpm = app.BPMEditField.Value;
            bps = bpm / 60;
            secperbeat = 1/bps;

            notDiv = 0.5;

            timesec = notDiv*secperbeat;


            d = fix(timesec*app.Fs);
            %d = (app.BeatsPerMinuteofAudioSpinner.Value*app.Fs);
            a = -0.75;
            N = length(app.signal); 
            y = zeros(N, 1);

            for n = 1:N
                if n < d + 1;
                    y(n, 1) = app.signal(n, 1);

                else
                    y(n, 1) = app. signal(n,1) + (-a)*y(n-d, 1);
                end
            end
            app.signal = y;
            plotSignal(app);
        end

        % Button pushed function: AddConvolutionReverbButton
        function AddConvolutionReverbButtonPushed(app, event)
            app.beforeEffect = app.signal;
            
            [h] = audioread('reverbIR.wav');
            [x] = app.signal;
            yLeft = conv(x, h(:,1));
            yRight = conv(x,h(:,2));

            app.signal = [yLeft, yRight];
            plotSignal(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            str = strcat(app.file(1:length(app.file)-4), "New", ".wav");
            app.troubleshootTextArea.Value = str;
            audiowrite(str, app.signal, app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectAudioFIleButtonPushed, true);
            app.InputSignal = uiaxes(app.UIFigure);
            title(app.InputSignal, 'Input Audio')
            xlabel(app.InputSignal, 'Time (s)')
            ylabel(app.InputSignal, 'Amplitude')
            zlabel(app.InputSignal, 'Z')
            app.SelectAudioFIleButton = uibutton(app.UIFigure, 'push');
            app.SelectAudioFIleButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioFIleButtonPushed, true);
            app.SelectAudioFIleButton.Text = 'Select Audio FIle';
            app.SelectAudioFIleText = uitextarea(app.UIFigure);
            app.SelectAudioFIleText.Editable = 'off';
            app.SelectAudioFIleText.Visible = 'off';
            app.StartTimesEditField_2Label = uilabel(app.UIFigure);
            app.StartTimesEditField_2Label.Text = 'Start Time (s)';
            app.StartFadeInTime = uieditfield(app.UIFigure, 'numeric');
            app.StartFadeInTime.Limits = [0 Inf];
            app.StartFadeInTime.RoundFractionalValues = 'on';
            app.EndTimesEditField_2Label = uilabel(app.UIFigure);
            app.EndTimesEditField_2Label.Text = 'End Time (s)';
            app.EndFadeInTime = uieditfield(app.UIFigure, 'numeric');
            app.EndFadeInTime.Limits = [0 Inf];
            app.EndFadeInTime.RoundFractionalValues = 'on';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FadeDegreeSliderLabel = uilabel(app.UIFigure);
            app.FadeDegreeSliderLabel.Text = 'Fade Degree ';
            app.FadeDegreeSlider = uislider(app.UIFigure);
            app.FadeDegreeSlider.Limits = [1 5];
            app.FadeDegreeSlider.MajorTicks = [1 2 3 4 5];
            app.FadeDegreeSlider.MinorTicks = [];
            app.FadeDegreeSlider.Value = 1;
            app.TypeOfFade = uibuttongroup(app.UIFigure);
            app.FadeInButton = uiradiobutton(app.TypeOfFade);
            app.FadeInButton.Text = 'Fade In';
            app.FadeInButton.Value = true;
            app.FadeOutButton = uiradiobutton(app.TypeOfFade);
            app.FadeOutButton.Text = 'Fade Out';
            app.AddFadeEffectButton = uibutton(app.UIFigure, 'push');
            app.AddFadeEffectButton.ButtonPushedFcn = createCallbackFcn(app, @AddFadeEffectButtonPushed, true);
            app.AddFadeEffectButton.Text = 'Add Fade Effect';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.UndoLastEffectButton = uibutton(app.UIFigure, 'push');
            app.UndoLastEffectButton.ButtonPushedFcn = createCallbackFcn(app, @UndoLastEffectButtonPushed, true);
            app.UndoLastEffectButton.Text = 'Undo Last Effect';
            app.AddTremoloEffectButton = uibutton(app.UIFigure, 'push');
            app.AddTremoloEffectButton.ButtonPushedFcn = createCallbackFcn(app, @AddTremoloEffectButtonPushed, true);
            app.AddTremoloEffectButton.Text = 'Add Tremolo Effect';
            app.LFOFrequencyHzSpinnerLabel = uilabel(app.UIFigure);
            app.LFOFrequencyHzSpinnerLabel.Text = 'LFO Frequency (Hz)';
            app.LFOFrequencyHzSpinner = uispinner(app.UIFigure);
            app.LFOFrequencyHzSpinner.Step = 0.1;
            app.LFOFrequencyHzSpinner.Limits = [0.1 20];
            app.LFOFrequencyHzSpinner.Value = 0.1;
            app.FadeEffectErrorText = uitextarea(app.UIFigure);
            app.FadeEffectErrorText.Editable = 'off';
            app.FadeEffectErrorText.Visible = 'off';
            app.AmplitudeDepthLabel = uilabel(app.UIFigure);
            app.AmplitudeDepthLabel.Text = 'Amplitude Depth (%)';
            app.AmplitudeDepthSlider = uislider(app.UIFigure);
            app.TremoloEffectOptions = uilistbox(app.UIFigure);
            app.TremoloEffectOptions.Items = {'Sine Wave', 'SawTooth', 'Square', 'Triangle'};
            app.TremoloEffectOptions.Value = 'Sine Wave';
            app.StartTimesEditField_2Label_2 = uilabel(app.UIFigure);
            app.StartTimesEditField_2Label_2.Text = 'Start Time (s)';
            app.StartTremoloEffect = uieditfield(app.UIFigure, 'numeric');
            app.StartTremoloEffect.Limits = [0 Inf];
            app.StartTremoloEffect.RoundFractionalValues = 'on';
            app.EndTimesEditField_2Label_2 = uilabel(app.UIFigure);
            app.EndTimesEditField_2Label_2.Text = 'End Time (s)';
            app.EndTremoloEffect = uieditfield(app.UIFigure, 'numeric');
            app.EndTremoloEffect.Limits = [0 Inf];
            app.EndTremoloEffect.RoundFractionalValues = 'on';
            app.TremeloEffectErrorText = uitextarea(app.UIFigure);
            app.TremeloEffectErrorText.Editable = 'off';
            app.TremeloEffectErrorText.Visible = 'off';
            app.AddRythmaticAutoPanningEffectButton = uibutton(app.UIFigure, 'push');
            app.AddRythmaticAutoPanningEffectButton.ButtonPushedFcn = createCallbackFcn(app, @AddRythmaticAutoPanningEffectButtonPushed, true);
            app.AddRythmaticAutoPanningEffectButton.Text = 'Add Rythmatic Auto Panning Effect';
            app.AddEchoEffectButton = uibutton(app.UIFigure, 'push');
            app.AddEchoEffectButton.ButtonPushedFcn = createCallbackFcn(app, @AddEchoEffectButtonPushed, true);
            app.AddEchoEffectButton.Text = 'Add Echo Effect';
            app.AddConvolutionReverbButton = uibutton(app.UIFigure, 'push');
            app.AddConvolutionReverbButton.ButtonPushedFcn = createCallbackFcn(app, @AddConvolutionReverbButtonPushed, true);
            app.AddConvolutionReverbButton.Text = 'Add Convolution Reverb';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.BPMEditFieldLabel = uilabel(app.UIFigure);
            app.BPMEditFieldLabel.Text = 'BPM ';
            app.BPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPMEditField.Limits = [0 Inf];
            app.troubleshootTextAreaLabel = uilabel(app.UIFigure);
            app.troubleshootTextAreaLabel.Text = 'troubleshoot';
            app.troubleshootTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSE491Project1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end